classdef PIDTEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlotTimeValuesCheckBox   matlab.ui.control.CheckBox
        TimeParametersTextArea   matlab.ui.control.TextArea
        Lamp                     matlab.ui.control.Lamp
        Label                    matlab.ui.control.Label
        HoldSwitch               matlab.ui.control.Switch
        HoldSwitchLabel          matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        DenEditField             matlab.ui.control.EditField
        DenEditFieldLabel        matlab.ui.control.Label
        NumEditField             matlab.ui.control.EditField
        NumEditFieldLabel        matlab.ui.control.Label
        CreatedByEditField       matlab.ui.control.EditField
        CreatedByEditFieldLabel  matlab.ui.control.Label
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        KdSlider                 matlab.ui.control.Slider
        KdSliderLabel            matlab.ui.control.Label
        KiSlider                 matlab.ui.control.Slider
        KiSliderLabel            matlab.ui.control.Label
        KpSlider                 matlab.ui.control.Slider
        KpSliderLabel            matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %num=1;
            %den=[1 10 20];
            num=str2num(app.NumEditField.Value);
            den=str2num(app.DenEditField.Value);
            P=tf(num,den);
            %C = pid(app.KpSlider.Value,app.KiSlider.Value,app.KdSlider.Value);
            C = pid(0,0,0);
            T = feedback(C*P,1);
            t = 0:0.01:10;
            y=step(T,t);
            %S=stepinfo(y)
            plot(app.UIAxes,t,y);
            S1=stepinfo(y,t);
            text1='Rise Time: ';
            text2='Settling Time: ';
            text3='Peak Value: ';
            text4='Peak Time: ';
            app.TimeParametersTextArea.Value=[text1, num2str(S1.RiseTime),sprintf('\n'), 'Settling Time: ', num2str(S1.SettlingTime),sprintf('\n'), 'Peak Value: ',num2str(S1.Peak), sprintf('\n'),'Peak TIme: ',num2str(S1.PeakTime)];
        end

        % Value changed function: KdSlider
        function KdSliderValueChanged(app, event)
            %num=1;
            %den=[1 10 20];
            num=str2num(app.NumEditField.Value);
            den=str2num(app.DenEditField.Value);
            P=tf(num,den);
            C = pid(app.KpSlider.Value,app.KiSlider.Value,app.KdSlider.Value);
            T = feedback(C*P,1);
            t = 0:0.01:10;
            y=step(T,t);
            plot(app.UIAxes,t,y);
            S1=stepinfo(y,t)
            app.TimeParametersTextArea.Value=['Rise Time: ', num2str(S1.RiseTime),sprintf('\n'), 'Settling Time: ', num2str(S1.SettlingTime),sprintf('\n'), 'Peak Value: ',num2str(S1.Peak), sprintf('\n'),'Peak TIme: ',num2str(S1.PeakTime)];
            %hold(app.UIAxes);
        end

        % Value changed function: KiSlider
        function KiSliderValueChanged(app, event)
            %num=1;
            %den=[1 10 20];
            num=str2num(app.NumEditField.Value);
            den=str2num(app.DenEditField.Value);
            P=tf(num,den);
            C = pid(app.KpSlider.Value,app.KiSlider.Value,app.KdSlider.Value);
            T = feedback(C*P,1);
            t = 0:0.01:10;
            y=step(T,t);
            plot(app.UIAxes,t,y);
            S1=stepinfo(y,t)
            app.TimeParametersTextArea.Value=['Rise Time: ', num2str(S1.RiseTime),sprintf('\n'), 'Settling Time: ', num2str(S1.SettlingTime),sprintf('\n'), 'Peak Value: ',num2str(S1.Peak), sprintf('\n'),'Peak TIme: ',num2str(S1.PeakTime)];
            %hold(app.UIAxes);
        end

        % Value changed function: KpSlider
        function KpSliderValueChanged(app, event)
            %num=1;
            %den=[1 10 20];
            num=str2num(app.NumEditField.Value);
            den=str2num(app.DenEditField.Value);
            P=tf(num,den);
            C = pid(app.KpSlider.Value,app.KiSlider.Value,app.KdSlider.Value);
            T = feedback(C*P,1);
            t = 0:0.01:10;
            y=step(T,t);
            plot(app.UIAxes,t,y);
            S1=stepinfo(y,t)
            text1='Rise Time: '
            %set(text1,'Color','r')
            app.TimeParametersTextArea.Value=[text1, num2str(S1.RiseTime),sprintf('\n'), 'Settling Time: ', num2str(S1.SettlingTime),sprintf('\n'), 'Peak Value: ',num2str(S1.Peak), sprintf('\n'),'Peak TIme: ',num2str(S1.PeakTime)];
            
        end

        % Value changed function: NumEditField
        function NumEditFieldValueChanged(app, event)
           %num=str2num(app.NumEditField.Value);
        end

        % Value changed function: DenEditField
        function DenEditFieldValueChanged(app, event)
            %den=str2num(app.DenEditField.Value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %PIDTEST();
            %close(app.UIFigure)
            cla(app.UIAxes);
            app.KpSlider.Value=0;
            app.KdSlider.Value=0;
            app.KiSlider.Value=0;
            startupFcn(app);
        end

        % Value changed function: HoldSwitch
        function HoldSwitchValueChanged(app, event)
            value = app.HoldSwitch.Value;
            if strcmp(value,'On')
                app.Lamp.Color='g';
                hold(app.UIAxes,"on");
            else
                app.Lamp.Color='r';
                hold(app.UIAxes,"off");
            end
        end

        % Value changed function: PlotTimeValuesCheckBox
        function PlotTimeValuesCheckBoxValueChanged(app, event)
            value = app.PlotTimeValuesCheckBox.Value;
            num=str2num(app.NumEditField.Value);
            den=str2num(app.DenEditField.Value);
            P=tf(num,den);
            C = pid(app.KpSlider.Value,app.KiSlider.Value,app.KdSlider.Value);
            T = feedback(C*P,1);
            t = 0:0.01:10;
            y=step(T,t);
            S1=stepinfo(y,t)
            text1='Rise Time (red): ';
            text2='Settling Time (green): ';
            text3='Peak Value (magenta): ';
            text4='Peak Time (blue): ';
            app.TimeParametersTextArea.Value=[text1, num2str(S1.RiseTime),sprintf('\n'), text2, num2str(S1.SettlingTime),sprintf('\n'), text3,num2str(S1.Peak), sprintf('\n'),text4,num2str(S1.PeakTime)];
            if value==1
                hold(app.UIAxes,'on');
                for i=1:length(t)
                    risetime(i)=S1.RiseTime;
                    settlingtime(i)=S1.SettlingTime;
                    peak(i)=S1.Peak;
                    peaktime(i)=S1.PeakTime;
                    desiredvalue(i)=1;
                end
                app.UIAxes.YLim=[0,S1.Peak+0.02];
                %app.UIAxes.YLim=[0,2];
                plot(app.UIAxes,risetime,t,'r--');
                plot(app.UIAxes,settlingtime,t,'g--');
                plot(app.UIAxes,peaktime,t,'b--');
                plot(app.UIAxes,t,peak,'m--');
                plot(app.UIAxes,t,desiredvalue,'k--');
                if strcmp(app.HoldSwitch.Value,'On')
                app.Lamp.Color='g';
                hold(app.UIAxes,"on");
                else
                app.Lamp.Color='r';
                hold(app.UIAxes,"off");
                end
                app.PlotTimeValuesCheckBox.Value=0;
            else
                if strcmp(app.HoldSwitch.Value,'On')
                app.Lamp.Color='g';
                hold(app.UIAxes,"on");
                else
                app.Lamp.Color='r';
                hold(app.UIAxes,"off");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Closed Loop Response')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.KpSliderLabel = uilabel(app.UIFigure);
            app.KpSliderLabel.Text = 'Kp';
            app.KpSlider = uislider(app.UIFigure);
            app.KpSlider.Limits = [0 400];
            app.KpSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            app.KiSliderLabel = uilabel(app.UIFigure);
            app.KiSliderLabel.Text = 'Ki';
            app.KiSlider = uislider(app.UIFigure);
            app.KiSlider.Limits = [0 400];
            app.KiSlider.ValueChangedFcn = createCallbackFcn(app, @KiSliderValueChanged, true);
            app.KdSliderLabel = uilabel(app.UIFigure);
            app.KdSliderLabel.Text = 'Kd';
            app.KdSlider = uislider(app.UIFigure);
            app.KdSlider.ValueChangedFcn = createCallbackFcn(app, @KdSliderValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'e-yantra.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'IITB.png');
            app.CreatedByEditFieldLabel = uilabel(app.UIFigure);
            app.CreatedByEditFieldLabel.Text = 'Created  By';
            app.CreatedByEditField = uieditfield(app.UIFigure, 'text');
            app.CreatedByEditField.Editable = 'off';
            app.CreatedByEditField.Value = ' Avinash Dubey';
            app.NumEditFieldLabel = uilabel(app.UIFigure);
            app.NumEditFieldLabel.Text = 'Num';
            app.NumEditField = uieditfield(app.UIFigure, 'text');
            app.NumEditField.ValueChangedFcn = createCallbackFcn(app, @NumEditFieldValueChanged, true);
            app.NumEditField.Value = '1';
            app.DenEditFieldLabel = uilabel(app.UIFigure);
            app.DenEditFieldLabel.Text = 'Den';
            app.DenEditField = uieditfield(app.UIFigure, 'text');
            app.DenEditField.ValueChangedFcn = createCallbackFcn(app, @DenEditFieldValueChanged, true);
            app.DenEditField.Value = '1,10,20';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HoldSwitchLabel = uilabel(app.UIFigure);
            app.HoldSwitchLabel.Text = 'Hold';
            app.HoldSwitch = uiswitch(app.UIFigure, 'slider');
            app.HoldSwitch.ValueChangedFcn = createCallbackFcn(app, @HoldSwitchValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.TimeParametersTextArea = uitextarea(app.UIFigure);
            app.TimeParametersTextArea.Editable = 'off';
            app.TimeParametersTextArea.Placeholder = 'None';
            app.PlotTimeValuesCheckBox = uicheckbox(app.UIFigure);
            app.PlotTimeValuesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotTimeValuesCheckBoxValueChanged, true);
            app.PlotTimeValuesCheckBox.Text = 'Plot Time Values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PIDTEST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end